classdef interface1D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportSquaredButton             matlab.ui.control.Button
        LaststepButton                  matlab.ui.control.Button
        TimeTraker                      matlab.ui.control.EditField
        TimestepcurrenttimeLabel        matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        TESTCASESPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Test5Button                     matlab.ui.control.Button
        Test4Button                     matlab.ui.control.Button
        Test3Button                     matlab.ui.control.Button
        Test2Button                     matlab.ui.control.Button
        Test1Button                     matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        LambdaDistributionoptionsDropDown  matlab.ui.control.DropDown
        LambdaDistributionoptionsDropDownLabel  matlab.ui.control.Label
        WithChemicalReactionsCheckBox   matlab.ui.control.CheckBox
        AnimationControlPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DisplayOptionDropDown           matlab.ui.control.DropDown
        DisplayOptionDropDownLabel      matlab.ui.control.Label
        AnimationSelectionListBox       matlab.ui.control.ListBox
        AnimationSelectionListBoxLabel  matlab.ui.control.Label
        AnimateStopButton               matlab.ui.control.Button
        AnimateStartButton              matlab.ui.control.Button
        SolverControlPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BCControlDropDown               matlab.ui.control.DropDown
        BCControlDropDownLabel          matlab.ui.control.Label
        CharactSpeedDropDown            matlab.ui.control.DropDown
        CharactSpeedDropDownLabel       matlab.ui.control.Label
        HLLCVariantDropDown             matlab.ui.control.DropDown
        HLLCVariantDropDownLabel        matlab.ui.control.Label
        wcontrolerDropDown              matlab.ui.control.DropDown
        wcontrolerDropDownLabel         matlab.ui.control.Label
        LimiterDropDown                 matlab.ui.control.DropDown
        LimiterDropDownLabel            matlab.ui.control.Label
        BasicControlsPanel              matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        C_CflSlider                     matlab.ui.control.Slider
        C_CflSliderLabel                matlab.ui.control.Label
        StoptimeEditField               matlab.ui.control.NumericEditField
        StoptimeEditFieldLabel          matlab.ui.control.Label
        NumberofCellsEditField          matlab.ui.control.NumericEditField
        NumberofCellsEditFieldLabel     matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        PruebaMessageareaButton         matlab.ui.control.Button
        MessageAreaEditField            matlab.ui.control.EditField
        MessageAreaEditFieldLabel       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        InitialConditionPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Div_txt                         matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        DominionDivisionatSlider        matlab.ui.control.Slider
        DominionDivisionatSliderLabel   matlab.ui.control.Label
        PR0_txt                         matlab.ui.control.EditField
        kgm3Label_6                     matlab.ui.control.Label
        UR0_txt                         matlab.ui.control.EditField
        kgm3Label_4                     matlab.ui.control.Label
        RhoR0_txt                       matlab.ui.control.EditField
        kgm3Label_2                     matlab.ui.control.Label
        p_r0Slider                      matlab.ui.control.Slider
        p_r0SliderLabel                 matlab.ui.control.Label
        u_r0Slider                      matlab.ui.control.Slider
        u_r0SliderLabel                 matlab.ui.control.Label
        rho_r0Slider                    matlab.ui.control.Slider
        rho_r0SliderLabel               matlab.ui.control.Label
        PL0_txt                         matlab.ui.control.EditField
        kgm3Label_5                     matlab.ui.control.Label
        UL0_txt                         matlab.ui.control.EditField
        kgm3Label_3                     matlab.ui.control.Label
        RhoL0_txt                       matlab.ui.control.EditField
        kgm3Label                       matlab.ui.control.Label
        p_l0Slider                      matlab.ui.control.Slider
        p_l0SliderLabel                 matlab.ui.control.Label
        u_l0Slider                      matlab.ui.control.Slider
        u_l0SliderLabel                 matlab.ui.control.Label
        rho_l0Slider                    matlab.ui.control.Slider
        rho_l0SliderLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UI_rectangle                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        U
        t
        lambda
        Animation_stop = 0 ;
        Simulation_gamma = 1.4
        Simulation_x = []
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.Animation_stop = 1;
            app.Lamp.Color = [1,0,0];
            app.RUNButton.Enable = 'off';
            drawnow;
            try
                l = app.LengthEditField.Value;
                n = app.NumberofCellsEditField.Value;
                args = {l,n,app.rho_l0Slider.Value,app.u_l0Slider.Value,app.p_l0Slider.Value,...
                    app.rho_r0Slider.Value,app.u_r0Slider.Value,app.p_r0Slider.Value};
                options = {app.C_CflSlider.Value,app.StoptimeEditField.Value,app.LimiterDropDown.Value,...
                    app.wcontrolerDropDown.Value,app.HLLCVariantDropDown.Value,...
                    app.CharactSpeedDropDown.Value,app.BCControlDropDown.Value,...
                    app.DominionDivisionatSlider.Value};
                gamma = app.GammaEditField.Value;
                if app.WithChemicalReactionsCheckBox.Value
                    [U,t,lambda] = inputLoopResultwQR(args{:},ones(1,n),options{:});
                else
                    [U,t] = inputLoopResult(args{:},gamma,options{:});
                    lambda = [];
                end
                app.U = U;
                app.t = t;
                app.lambda = lambda;
                app.Simulation_gamma = gamma;
                app.Simulation_x = ((1:n)-0.5)*l/n;
                app.Lamp.Color = [0,1,0];
                app.MessageAreaEditField.Value = sprintf('Completed: %d steps, t=%.6g s',numel(t)-1,t(end));
                LaststepButtonPushed(app,[]);
            catch ME
                app.MessageAreaEditField.Value = ['Error: ' ME.message];
            end
            app.RUNButton.Enable = 'on';

        end

        % Value changed function: rho_l0Slider
        function rho_l0SliderValueChanged(app, event)
            value = app.rho_l0Slider.Value;
            app.RhoL0_txt.Value = num2str(value);

        end

        % Value changed function: RhoL0_txt
        function RhoL0_txtValueChanged(app, event)
            value = app.RhoL0_txt.Value;
            app.rho_l0Slider.Value = str2double(value);
        end

        % Value changed function: u_l0Slider
        function u_l0SliderValueChanged(app, event)
            value = app.u_l0Slider.Value;
            app.UL0_txt.Value = num2str(value);
        end

        % Value changed function: UL0_txt
        function UL0_txtValueChanged(app, event)
            value = app.UL0_txt.Value;
            app.u_l0Slider.Value = str2double(value);
        end

        % Value changed function: p_l0Slider
        function p_l0SliderValueChanged(app, event)
            value = app.p_l0Slider.Value;
            app.PL0_txt.Value = num2str(value);
        end

        % Value changed function: PL0_txt
        function PL0_txtValueChanged(app, event)
            value = app.PL0_txt.Value;
            app.p_l0Slider.Value = str2double(value);
        end

        % Value changed function: rho_r0Slider
        function rho_r0SliderValueChanged(app, event)
            value = app.rho_r0Slider.Value;
            app.RhoR0_txt.Value = num2str(value);
        end

        % Value changed function: RhoR0_txt
        function RhoR0_txtValueChanged(app, event)
            value = app.RhoR0_txt.Value;
            app.rho_r0Slider.Value = str2double(value);
        end

        % Value changed function: u_r0Slider
        function u_r0SliderValueChanged(app, event)
            value = app.u_r0Slider.Value;
            app.UR0_txt.Value = num2str(value);
        end

        % Value changed function: UR0_txt
        function UR0_txtValueChanged(app, event)
            value = app.UR0_txt.Value;
            app.u_r0Slider.Value = str2double(value);
        end

        % Value changed function: p_r0Slider
        function p_r0SliderValueChanged(app, event)
            value = app.p_r0Slider.Value;
            app.PR0_txt.Value = num2str(value);
        end

        % Value changed function: PR0_txt
        function PR0_txtValueChanged(app, event)
            value = app.PR0_txt.Value;
            app.p_r0Slider.Value = str2double(value);
        end

        % Button pushed function: Test1Button
        function Test1ButtonPushed(app, event)

            % Regular Sod Shock tube Test

            % Rho L0
            rho_l0 = 1;
            app.RhoL0_txt.Value = num2str(rho_l0);
            app.rho_l0Slider.Value = rho_l0;
            % U L0
            u_l0 = 0;
            app.UL0_txt.Value = num2str(u_l0);
            app.u_l0Slider.Value = u_l0;
            % P L0
            p_l0 = 1;
            app.PL0_txt.Value = num2str(p_l0);
            app.p_l0Slider.Value = p_l0;

            % Rho R0
            rho_r0 = 0.125;
            app.RhoR0_txt.Value = num2str(rho_r0);
            app.rho_r0Slider.Value = rho_r0;
            % U R0
            u_r0 = 0;
            app.UR0_txt.Value = num2str(u_r0);
            app.u_r0Slider.Value = u_r0;
            % P R0
            p_r0 = 0.1;
            app.PR0_txt.Value = num2str(p_r0);
            app.p_r0Slider.Value = p_r0;


        end

        % Button pushed function: Test2Button
        function Test2ButtonPushed(app, event)
              %Sod Shock tube Test 2

            % Rho L0
            rho_l0 = 1;
            app.RhoL0_txt.Value = num2str(rho_l0);
            app.rho_l0Slider.Value = rho_l0;
            % U L0
            u_l0 = -2;
            app.UL0_txt.Value = num2str(u_l0);
            app.u_l0Slider.Value = u_l0;
            % P L0
            p_l0 = 0.4;
            app.PL0_txt.Value = num2str(p_l0);
            app.p_l0Slider.Value = p_l0;

            % Rho R0
            rho_r0 = 1;
            app.RhoR0_txt.Value = num2str(rho_r0);
            app.rho_r0Slider.Value = rho_r0;
            % U R0
            u_r0 = 2;
            app.UR0_txt.Value = num2str(u_r0);
            app.u_r0Slider.Value = u_r0;
            % P R0
            p_r0 = 0.4;
            app.PR0_txt.Value = num2str(p_r0);
            app.p_r0Slider.Value = p_r0;

        end

        % Button pushed function: Test3Button
        function Test3ButtonPushed(app, event)
            %Sod Shock tube Test 3

            % Rho L0
            rho_l0 = 1;
            app.RhoL0_txt.Value = num2str(rho_l0);
            app.rho_l0Slider.Value = rho_l0;
            % U L0
            u_l0 = 0;
            app.UL0_txt.Value = num2str(u_l0);
            app.u_l0Slider.Value = u_l0;
            % P L0
            p_l0 = 1000;
            app.PL0_txt.Value = num2str(p_l0);
            app.p_l0Slider.Value = p_l0;

            % Rho R0
            rho_r0 = 1;
            app.RhoR0_txt.Value = num2str(rho_r0);
            app.rho_r0Slider.Value = rho_r0;
            % U R0
            u_r0 = 0;
            app.UR0_txt.Value = num2str(u_r0);
            app.u_r0Slider.Value = u_r0;
            % P R0
            p_r0 = 0.01;
            app.PR0_txt.Value = num2str(p_r0);
            app.p_r0Slider.Value = p_r0;
        end

        % Button pushed function: Test4Button
        function Test4ButtonPushed(app, event)
            %Sod Shock tube Test 4

            % Rho L0
            rho_l0 = 5.99924;
            app.RhoL0_txt.Value = num2str(rho_l0);
            app.rho_l0Slider.Value = rho_l0;
            % U L0
            u_l0 = 19.5975;
            app.UL0_txt.Value = num2str(u_l0);
            app.u_l0Slider.Value = u_l0;
            % P L0
            p_l0 = 460.98;
            app.PL0_txt.Value = num2str(p_l0);
            app.p_l0Slider.Value = p_l0;

            % Rho R0
            rho_r0 = 5.99242;
            app.RhoR0_txt.Value = num2str(rho_r0);
            app.rho_r0Slider.Value = rho_r0;
            % U R0
            u_r0 = -6.19633;
            app.UR0_txt.Value = num2str(u_r0);
            app.u_r0Slider.Value = u_r0;
            % P R0
            p_r0 = 46.0950;
            app.PR0_txt.Value = num2str(p_r0);
            app.p_r0Slider.Value = p_r0;
        end

        % Button pushed function: Test5Button
        function Test5ButtonPushed(app, event)
            %Sod Shock tube Test 5

            % Rho L0
            rho_l0 = 1;
            app.RhoL0_txt.Value = num2str(rho_l0);
            app.rho_l0Slider.Value = rho_l0;
            % U L0
            u_l0 = -19.5975;
            app.UL0_txt.Value = num2str(u_l0);
            app.u_l0Slider.Value = u_l0;
            % P L0
            p_l0 = 1000;
            app.PL0_txt.Value = num2str(p_l0);
            app.p_l0Slider.Value = p_l0;

            % Rho R0
            rho_r0 = 1;
            app.RhoR0_txt.Value = num2str(rho_r0);
            app.rho_r0Slider.Value = rho_r0;
            % U R0
            u_r0 = -19.5975;
            app.UR0_txt.Value = num2str(u_r0);
            app.u_r0Slider.Value = u_r0;
            % P R0
            p_r0 = 0.01;
            app.PR0_txt.Value = num2str(p_r0);
            app.p_r0Slider.Value = p_r0;
        end

        % Value changed function: Div_txt
        function Div_txtValueChanged(app, event)
            value = app.Div_txt.Value;
            app.DominionDivisionatSlider.Value = str2double(value);
        end

        % Value changed function: DominionDivisionatSlider
        function DominionDivisionatSliderValueChanged(app, event)
            value = app.DominionDivisionatSlider.Value;
            app.Div_txt.Value = num2str(value);
        end

        % Value changed function: WithChemicalReactionsCheckBox
        function WithChemicalReactionsCheckBoxValueChanged(app, event)
            value = app.WithChemicalReactionsCheckBox.Value;

            lambda_opt = 'Lambda';

            if value

               % Current items

               Curr_item = app.DisplayOptionDropDown.Items;

               % New options to display.
               newOptions = lambda_opt;

               if ~ismember(lambda_opt,Curr_item)

                   app.DisplayOptionDropDown.Items = [Curr_item , {newOptions}];
               end

            else

                % Current items

                Curr_item = app.DisplayOptionDropDown.Items;

                % New options to remove.

                % lambda_opt = 'Lambda';

                optionToRemove = lambda_opt;

                idx = find(strcmp(Curr_item,optionToRemove));

                if ~isempty(idx)
                    Curr_item(idx) = [];
                    app.DisplayOptionDropDown.Items = Curr_item;
                end
            end


        end

        % Button pushed function: AnimateStartButton
        function AnimateStartButtonPushed(app, event)
            if isempty(app.U) || isempty(app.t)
                app.MessageAreaEditField.Value = 'Run the simulation first';
                return
            end
            app.Animation_stop = 0;
            app.AnimateStartButton.Enable = 'off';
            restore = onCleanup(@()set(app.AnimateStartButton,'Enable','on'));
            for k = 1:size(app.U,3)
                if app.Animation_stop, break; end
                gamma = app.Simulation_gamma;
                if ~isempty(app.lambda), gamma = (4.5-app.lambda(k,:))./(3.5-app.lambda(k,:)); end
                prim = uconsvToUprim(app.U(:,:,k),gamma);
                field = app.DisplayOptionDropDown.Value;
                switch field
                    case 'Density', data = prim(1,:);
                    case 'Velocity', data = prim(2,:);
                    case 'Pressure', data = prim(3,:);
                    case 'Lambda'
                        if isempty(app.lambda)
                            app.MessageAreaEditField.Value = 'Lambda requires a reactive simulation';
                            return
                        end
                        data = app.lambda(k,:);
                end
                plot(app.UIAxes,app.Simulation_x,data,'LineWidth',2);
                xlabel(app.UIAxes,'Position [m]'); ylabel(app.UIAxes,field);
                legend(app.UIAxes,field,'Location','best');
                imagesc(app.UI_rectangle,app.Simulation_x,[0,1],data);
                colorbar(app.UI_rectangle);
                app.TimeTraker.Value = num2str(app.t(k));
                drawnow;
            end

        end

        % Button pushed function: AnimateStopButton
        function AnimateStopButtonPushed(app, event)
            % cla(app.UIAxes);
            % cla(app.UI_rectangle);
            app.Animation_stop = 1;
        end

        % Button pushed function: PruebaMessageareaButton
        function PruebaMessageareaButtonPushed(app, event)
            app.MessageAreaEditField.Value = 'Hola';


        end

        % Callback function
        function Test1RepresentButtonPushed(app, event)


        end

        % Button pushed function: LaststepButton
        function LaststepButtonPushed(app, event)
    try
    % --- 0) Datos de la simulación ---
    U = app.U;
    t = app.t;
    if isempty(U) || isempty(t)
        app.MessageAreaEditField.Value = 'Run the simulation, missing Data';
        return
    end

    % --- 1) Parámetros y malla ---
    gamma = app.Simulation_gamma;
    X = app.Simulation_x;

    % --- 2) Último índice temporal ---
    kLast = size(U, 3);
    if ~isempty(app.lambda), gamma = (4.5-app.lambda(kLast,:))./(3.5-app.lambda(kLast,:)); end
    U_last_prim = uconsvToUprim(U(:,:,kLast), gamma);   % -> [3 x n]
    rho_last = U_last_prim(1, :);
    u_last   = U_last_prim(2, :);
    p_last   = U_last_prim(3, :);

    % --- 3) Selección según tu DropDown ---
    val = lower(string(app.DisplayOptionDropDown.Value)); % 'density'|'velocity'|'pressure'
    switch val
        case {'density','densidad'}
            yData   = rho_last;
            yLabel  = 'Density (\rho)';
            legName = 'Density';
            color   = [1 0 0];
        case {'velocity','velocidad'}
            yData   = u_last;
            yLabel  = 'Velocity (u)';
            legName = 'Velocity';
            color   = [0 1 0];
        case 'lambda'
            if isempty(app.lambda)
                app.MessageAreaEditField.Value = 'Lambda requires a reactive simulation';
                return
            end
            yData = app.lambda(kLast,:);
            yLabel = 'Reactant fraction'; legName = 'Lambda'; color = [0.7,0,0.7];
        otherwise % 'pressure' / 'presión'
            yData   = p_last;
            yLabel  = 'Pressure (p)';
            legName = 'Pressure';
            color   = [0 0 1];
    end

    % --- 4) Limpiar y representar en los ejes ---
    cla(app.UIAxes);
    cla(app.UI_rectangle);

    % Curva 1D
    plot(app.UIAxes, X, yData, 'Color', color, 'LineWidth', 2);
    grid(app.UIAxes, 'on');
    xlabel(app.UIAxes, 'Position [m]');
    ylabel(app.UIAxes, yLabel);
    legend(app.UIAxes, {legName}, 'Location', 'best');

    % “Heatmap” 1D
    imagesc(app.UI_rectangle, X, [0,1], yData);
    axis(app.UI_rectangle, 'tight');
    colormap(app.UI_rectangle, 'jet');
    hcb = colorbar(app.UI_rectangle);
    ylabel(hcb, yLabel);

    % --- 5) Actualiza el tracker de tiempo con el último t ---
    app.TimeTraker.Value = num2str(t(kLast));

catch ME
    app.MessageAreaEditField.Value = ['Error: ' ME.message];
end
        end

        % Button pushed function: ExportSquaredButton
        function ExportSquaredButtonPushed(app, event)

try
    % --- Parámetros del PNG cuadrado ---
    sidePx = 1000;          % tamaño del PNG (1000x1000). Cambia si quieres
    dpi    = 300;           % resolución de salida

    % --- Solicitar rango de eje Y ---
    answer = inputdlg({'Ymin:', 'Ymax:'}, 'Rango del eje Y', [1 30], {'0','0'});
    if isempty(answer), return; end   % si cancelas, salir
    ymin = str2double(answer{1});
    ymax = str2double(answer{2});

    % --- Pide nombre de archivo ---
    [fname, fpath] = uiputfile({'*.png','PNG'}, 'Guardar PNG cuadrado', 'axes_export.png');
    if isequal(fname,0), return; end
    outFile = fullfile(fpath, fname);

    % --- Crea una figura temporal cuadrada e invisible ---
    fig = figure('Visible','off','Color','w', ...
                 'Units','pixels','Position',[100 100 sidePx sidePx]);

    % --- Copia tu UIAxes a la figura temporal ---
    ax2 = copyobj(app.UIAxes, fig);
    set(ax2, 'Units','normalized', 'Position',[0.20 0.18 0.74 0.74]);

    % --- Ajustes de estilo ---
    ax2.FontSize   = 12;   % ticks grandes
    ax2.FontWeight = 'bold';

    % Etiquetas de ejes
    ax2.XLabel.FontSize   = 14;
    ax2.YLabel.FontSize   = 14;
    ax2.XLabel.FontWeight = 'bold';
    ax2.YLabel.FontWeight = 'bold';
    ax2.XLabel.Margin     = 15;   % separación extra
    ax2.YLabel.Margin     = 15;

    % Título
    ax2.Title.FontSize   = 16;
    ax2.Title.FontWeight = 'bold';

    % Líneas más gruesas
    lines = findall(ax2, 'Type', 'Line');
    set(lines, 'LineWidth', 2.5);

    % --- Aplicar rango de Y si corresponde ---
    if ~(ymin==0 && ymax==0)
        ax2.YLim = [ymin ymax];
    end


    % --- Exporta a PNG cuadrado ---
    set(fig,'PaperUnits','inches','PaperPositionMode','manual',...
        'PaperPosition',[0,0,sidePx/dpi,sidePx/dpi],'PaperSize',[sidePx/dpi,sidePx/dpi]);
    print(fig,outFile,'-dpng',sprintf('-r%d',dpi));

    % --- Cierra la figura temporal ---
    close(fig);

    app.MessageAreaEditField.Value = ['Exportado: ' outFile];

catch ME
    uialert(app.UIFigure, ME.message, 'Export PNG');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UI_rectangle = uiaxes(app.UIFigure);
            app.UI_rectangle.XTick = [];
            app.UI_rectangle.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InitialConditionPanel = uipanel(app.UIFigure);
            app.InitialConditionPanel.Title = 'Initial Condition ';
            app.GridLayout3 = uigridlayout(app.InitialConditionPanel);
            app.rho_l0SliderLabel = uilabel(app.GridLayout3);
            app.rho_l0SliderLabel.Text = 'rho_l0';
            app.rho_l0Slider = uislider(app.GridLayout3);
            app.rho_l0Slider.Limits = [0 20];
            app.rho_l0Slider.ValueChangedFcn = createCallbackFcn(app, @rho_l0SliderValueChanged, true);
            app.rho_l0Slider.Value = 1;
            app.u_l0SliderLabel = uilabel(app.GridLayout3);
            app.u_l0SliderLabel.Text = 'u_l0';
            app.u_l0Slider = uislider(app.GridLayout3);
            app.u_l0Slider.Limits = [-20 20];
            app.u_l0Slider.ValueChangedFcn = createCallbackFcn(app, @u_l0SliderValueChanged, true);
            app.p_l0SliderLabel = uilabel(app.GridLayout3);
            app.p_l0SliderLabel.Text = 'p_l0';
            app.p_l0Slider = uislider(app.GridLayout3);
            app.p_l0Slider.Limits = [0 20000000];
            app.p_l0Slider.ValueChangedFcn = createCallbackFcn(app, @p_l0SliderValueChanged, true);
            app.p_l0Slider.Value = 1;
            app.kgm3Label = uilabel(app.GridLayout3);
            app.kgm3Label.Text = 'kg/m^3';
            app.RhoL0_txt = uieditfield(app.GridLayout3, 'text');
            app.RhoL0_txt.ValueChangedFcn = createCallbackFcn(app, @RhoL0_txtValueChanged, true);
            app.kgm3Label_3 = uilabel(app.GridLayout3);
            app.kgm3Label_3.Text = 'm/s';
            app.UL0_txt = uieditfield(app.GridLayout3, 'text');
            app.UL0_txt.ValueChangedFcn = createCallbackFcn(app, @UL0_txtValueChanged, true);
            app.kgm3Label_5 = uilabel(app.GridLayout3);
            app.kgm3Label_5.Text = 'N/m^2';
            app.PL0_txt = uieditfield(app.GridLayout3, 'text');
            app.PL0_txt.ValueChangedFcn = createCallbackFcn(app, @PL0_txtValueChanged, true);
            app.rho_r0SliderLabel = uilabel(app.GridLayout3);
            app.rho_r0SliderLabel.Text = 'rho_r0';
            app.rho_r0Slider = uislider(app.GridLayout3);
            app.rho_r0Slider.Limits = [0 20];
            app.rho_r0Slider.ValueChangedFcn = createCallbackFcn(app, @rho_r0SliderValueChanged, true);
            app.rho_r0Slider.Value = 0.125;
            app.u_r0SliderLabel = uilabel(app.GridLayout3);
            app.u_r0SliderLabel.Text = 'u_r0';
            app.u_r0Slider = uislider(app.GridLayout3);
            app.u_r0Slider.Limits = [-20 20];
            app.u_r0Slider.ValueChangedFcn = createCallbackFcn(app, @u_r0SliderValueChanged, true);
            app.p_r0SliderLabel = uilabel(app.GridLayout3);
            app.p_r0SliderLabel.Text = 'p_r0';
            app.p_r0Slider = uislider(app.GridLayout3);
            app.p_r0Slider.Limits = [0 20000000];
            app.p_r0Slider.ValueChangedFcn = createCallbackFcn(app, @p_r0SliderValueChanged, true);
            app.p_r0Slider.Value = 0.1;
            app.kgm3Label_2 = uilabel(app.GridLayout3);
            app.kgm3Label_2.Text = 'kg/m^3';
            app.RhoR0_txt = uieditfield(app.GridLayout3, 'text');
            app.RhoR0_txt.ValueChangedFcn = createCallbackFcn(app, @RhoR0_txtValueChanged, true);
            app.kgm3Label_4 = uilabel(app.GridLayout3);
            app.kgm3Label_4.Text = 'm/s';
            app.UR0_txt = uieditfield(app.GridLayout3, 'text');
            app.UR0_txt.ValueChangedFcn = createCallbackFcn(app, @UR0_txtValueChanged, true);
            app.kgm3Label_6 = uilabel(app.GridLayout3);
            app.kgm3Label_6.Text = 'N/m^2';
            app.PR0_txt = uieditfield(app.GridLayout3, 'text');
            app.PR0_txt.ValueChangedFcn = createCallbackFcn(app, @PR0_txtValueChanged, true);
            app.DominionDivisionatSliderLabel = uilabel(app.GridLayout3);
            app.DominionDivisionatSliderLabel.Text = 'Dominion Division at:';
            app.DominionDivisionatSlider = uislider(app.GridLayout3);
            app.DominionDivisionatSlider.ValueChangedFcn = createCallbackFcn(app, @DominionDivisionatSliderValueChanged, true);
            app.DominionDivisionatSlider.Value = 50;
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '%';
            app.Div_txt = uieditfield(app.GridLayout3, 'text');
            app.Div_txt.CharacterLimits = [0 100];
            app.Div_txt.ValueChangedFcn = createCallbackFcn(app, @Div_txtValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.MessageAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MessageAreaEditFieldLabel.Text = 'Message Area';
            app.MessageAreaEditField = uieditfield(app.UIFigure, 'text');
            app.PruebaMessageareaButton = uibutton(app.UIFigure, 'push');
            app.PruebaMessageareaButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaMessageareaButtonPushed, true);
            app.PruebaMessageareaButton.Visible = 'off';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.GridLayout6 = uigridlayout(app.Panel2);
            app.BasicControlsPanel = uipanel(app.GridLayout6);
            app.BasicControlsPanel.Title = 'Basic Controls';
            app.GridLayout = uigridlayout(app.BasicControlsPanel);
            app.LengthEditFieldLabel = uilabel(app.GridLayout);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthEditField.Limits = [0 1];
            app.LengthEditField.Value = 1;
            app.NumberofCellsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofCellsEditFieldLabel.Text = 'Number of Cells';
            app.NumberofCellsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofCellsEditField.Limits = [0 1000];
            app.NumberofCellsEditField.Value = 100;
            app.StoptimeEditFieldLabel = uilabel(app.GridLayout);
            app.StoptimeEditFieldLabel.Text = 'Stop time ';
            app.StoptimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.StoptimeEditField.Limits = [0 1];
            app.StoptimeEditField.Value = 0.1;
            app.C_CflSliderLabel = uilabel(app.GridLayout);
            app.C_CflSliderLabel.Text = 'C_Cfl';
            app.C_CflSlider = uislider(app.GridLayout);
            app.C_CflSlider.Limits = [0.01 0.5];
            app.C_CflSlider.Value = 0.1;
            app.GammaEditFieldLabel = uilabel(app.GridLayout);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.GridLayout, 'numeric');
            app.GammaEditField.Limits = [0 2];
            app.GammaEditField.Value = 1.4;
            app.SolverControlPanel = uipanel(app.GridLayout6);
            app.SolverControlPanel.Title = 'Solver Control';
            app.GridLayout2 = uigridlayout(app.SolverControlPanel);
            app.LimiterDropDownLabel = uilabel(app.GridLayout2);
            app.LimiterDropDownLabel.Text = 'Limiter';
            app.LimiterDropDown = uidropdown(app.GridLayout2);
            app.LimiterDropDown.Items = {'VanLeer', 'Albada', 'MINBEE', 'Superbee', 'Unos'};
            app.LimiterDropDown.Value = 'MINBEE';
            app.wcontrolerDropDownLabel = uilabel(app.GridLayout2);
            app.wcontrolerDropDownLabel.Text = 'w controler';
            app.wcontrolerDropDown = uidropdown(app.GridLayout2);
            app.wcontrolerDropDown.Items = {'1', '-1', '0.5', 'Sound Speed Controlled'};
            app.wcontrolerDropDown.Value = 'Sound Speed Controlled';
            app.HLLCVariantDropDownLabel = uilabel(app.GridLayout2);
            app.HLLCVariantDropDownLabel.Text = 'HLLC Variant';
            app.HLLCVariantDropDown = uidropdown(app.GridLayout2);
            app.HLLCVariantDropDown.Items = {'Variante 1 - Original', 'Variante_2'};
            app.HLLCVariantDropDown.Value = 'Variante_2';
            app.CharactSpeedDropDownLabel = uilabel(app.GridLayout2);
            app.CharactSpeedDropDownLabel.Text = 'Charact Speed ';
            app.CharactSpeedDropDown = uidropdown(app.GridLayout2);
            app.CharactSpeedDropDown.Items = {'Pressure Based', 'Enthalpy Based'};
            app.CharactSpeedDropDown.Value = 'Pressure Based';
            app.BCControlDropDownLabel = uilabel(app.GridLayout2);
            app.BCControlDropDownLabel.Text = 'BC Control';
            app.BCControlDropDown = uidropdown(app.GridLayout2);
            app.BCControlDropDown.Items = {'Reflective', 'Open', 'Periodic'};
            app.BCControlDropDown.Value = 'Open';
            app.AnimationControlPanel = uipanel(app.GridLayout6);
            app.AnimationControlPanel.Title = 'Animation Control ';
            app.GridLayout5 = uigridlayout(app.AnimationControlPanel);
            app.AnimateStartButton = uibutton(app.GridLayout5, 'push');
            app.AnimateStartButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateStartButtonPushed, true);
            app.AnimateStartButton.Text = 'Animate - Start ';
            app.AnimateStopButton = uibutton(app.GridLayout5, 'push');
            app.AnimateStopButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateStopButtonPushed, true);
            app.AnimateStopButton.Text = 'Animate- Stop';
            app.AnimationSelectionListBoxLabel = uilabel(app.GridLayout5);
            app.AnimationSelectionListBoxLabel.Text = 'Animation Selection';
            app.AnimationSelectionListBox = uilistbox(app.GridLayout5);
            app.AnimationSelectionListBox.Items = {'Velocity', 'Pressure', 'Density'};
            app.AnimationSelectionListBox.Multiselect = 'on';
            app.AnimationSelectionListBox.Value = {'Velocity'};
            app.DisplayOptionDropDownLabel = uilabel(app.GridLayout5);
            app.DisplayOptionDropDownLabel.Text = 'Display Option';
            app.DisplayOptionDropDown = uidropdown(app.GridLayout5);
            app.DisplayOptionDropDown.Items = {'Velocity', 'Density', 'Pressure'};
            app.DisplayOptionDropDown.Value = 'Velocity';
            app.Panel = uipanel(app.GridLayout6);
            app.Panel.Title = 'Panel';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.WithChemicalReactionsCheckBox = uicheckbox(app.GridLayout4);
            app.WithChemicalReactionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithChemicalReactionsCheckBoxValueChanged, true);
            app.WithChemicalReactionsCheckBox.Text = ' With Chemical Reactions';
            app.LambdaDistributionoptionsDropDownLabel = uilabel(app.GridLayout4);
            app.LambdaDistributionoptionsDropDownLabel.Text = 'Lambda Distribution options';
            app.LambdaDistributionoptionsDropDown = uidropdown(app.GridLayout4);
            app.LambdaDistributionoptionsDropDown.Items = {'1 : All cells'};
            app.LambdaDistributionoptionsDropDown.Value = '1 : All cells';
            app.TESTCASESPanel = uipanel(app.UIFigure);
            app.TESTCASESPanel.Title = 'TEST CASES';
            app.GridLayout7 = uigridlayout(app.TESTCASESPanel);
            app.Test1Button = uibutton(app.GridLayout7, 'push');
            app.Test1Button.ButtonPushedFcn = createCallbackFcn(app, @Test1ButtonPushed, true);
            app.Test1Button.Text = 'Test 1';
            app.Test2Button = uibutton(app.GridLayout7, 'push');
            app.Test2Button.ButtonPushedFcn = createCallbackFcn(app, @Test2ButtonPushed, true);
            app.Test2Button.Text = 'Test 2';
            app.Test3Button = uibutton(app.GridLayout7, 'push');
            app.Test3Button.ButtonPushedFcn = createCallbackFcn(app, @Test3ButtonPushed, true);
            app.Test3Button.Text = 'Test 3';
            app.Test4Button = uibutton(app.GridLayout7, 'push');
            app.Test4Button.ButtonPushedFcn = createCallbackFcn(app, @Test4ButtonPushed, true);
            app.Test4Button.Text = 'Test 4';
            app.Test5Button = uibutton(app.GridLayout7, 'push');
            app.Test5Button.ButtonPushedFcn = createCallbackFcn(app, @Test5ButtonPushed, true);
            app.Test5Button.Text = 'Test 5';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TimestepcurrenttimeLabel = uilabel(app.UIFigure);
            app.TimestepcurrenttimeLabel.Text = 'Time step current time';
            app.TimeTraker = uieditfield(app.UIFigure, 'text');
            app.LaststepButton = uibutton(app.UIFigure, 'push');
            app.LaststepButton.ButtonPushedFcn = createCallbackFcn(app, @LaststepButtonPushed, true);
            app.LaststepButton.Text = 'Last step ';
            app.ExportSquaredButton = uibutton(app.UIFigure, 'push');
            app.ExportSquaredButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSquaredButtonPushed, true);
            app.ExportSquaredButton.Text = 'Export Squared';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface1D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end